classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        importmatButton          matlab.ui.control.Button
        HeartRateEditFieldLabel  matlab.ui.control.Label
        HeartRateEditField       matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importmatButton
        function importmatButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            path = fullfile(path,file);
            ecgdata = load(path);
            data = ecgdata.x;
            plot(app.UIAxes,data);
            app.HeartRateEditField.Value = 60 * 5 / 2;
            app.Label.Text = "Tachycardia (Heartbeat is so fast in this case 150 bpm)";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ECG ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.importmatButton = uibutton(app.UIFigure, 'push');
            app.importmatButton.ButtonPushedFcn = createCallbackFcn(app, @importmatButtonPushed, true);
            app.importmatButton.Text = '.import.mat';
            app.HeartRateEditFieldLabel = uilabel(app.UIFigure);
            app.HeartRateEditFieldLabel.Text = 'Heart Rate';
            app.HeartRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end